classdef TennisProjectGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        TennisTrackerTab            matlab.ui.container.Tab
        Image                       matlab.ui.control.Image
        StereoCalibratorTab         matlab.ui.container.Tab
        GuideTextArea               matlab.ui.control.TextArea
        GuideTextAreaLabel          matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        PropogationErrorLabel       matlab.ui.control.Label
        BoardPlacementVisualizationLabel  matlab.ui.control.Label
        RefreshButton               matlab.ui.control.Button
        AddImagesButton             matlab.ui.control.Button
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        CORTab                      matlab.ui.container.Tab
        SurfaceDropDown             matlab.ui.control.DropDown
        SurfaceDropDownLabel        matlab.ui.control.Label
        CORTable                    matlab.ui.control.Table
        MeasureCORButton            matlab.ui.control.Button
        BaselineCORButton           matlab.ui.control.Button
        CORAxes                     matlab.ui.control.UIAxes
        AccuracyPlotsTab            matlab.ui.container.Tab
        LoadMeasuredDataButton      matlab.ui.control.Button
        LoadGroundTruthButton       matlab.ui.control.Button
        OverallAccuracyLabel        matlab.ui.control.Label
        AccuracyLabel               matlab.ui.control.Label
        ErrorLabel                  matlab.ui.control.Label
        SystemAccuracyLabel         matlab.ui.control.Label
        BasedonCameraPositionLabel  matlab.ui.control.Label
        BasedonBallPositionLabel    matlab.ui.control.Label
        BallAccuracyZ               matlab.ui.control.UIAxes
        BallAccuracyY               matlab.ui.control.UIAxes
        BallAccuracyX               matlab.ui.control.UIAxes
        CamAccuracy                 matlab.ui.control.UIAxes
        PlaybackTab                 matlab.ui.container.Tab
        ReplayButton                matlab.ui.control.Button
        ServeVolleyButton           matlab.ui.control.Button
        ReplayLabel                 matlab.ui.control.Label
        TrajectoryLabel             matlab.ui.control.Label
        PlayBackMenuLabel           matlab.ui.control.Label
        TrajectoryGraph             matlab.ui.control.UIAxes
        ReplayGraph                 matlab.ui.control.UIAxes
        SettingsTab                 matlab.ui.container.Tab
    end

    
    properties (Access = private)
        Property % Description
        LeftImageFolderPath  % Path to selected Left Images
        RightImageFolderPath % Path to selected Right Images
        CORFolderPath string % Path to COR Scripts
        Client %connection to blender
        sampledServe % data plots
        GroundTruthData % Expected Values for accuracy plots
        MeasuredData % Calculated Values for plots
    end
    
    methods (Access = private)
%----------------------------------------------------------------------------------------------------------------------------------------------------------------
        function plotExtrinsics(app, stereoParams)
         tempFig = figure('Visible', 'off');
         showExtrinsics(stereoParams, 'CameraCentric');
         tempAx = gca;

         cla(app.UIAxes_2);
            copyobj(allchild(tempAx), app.UIAxes_2);
         app.UIAxes_2.XLim = tempAx.XLim;
         app.UIAxes_2.YLim = tempAx.YLim;
         app.UIAxes_2.ZLim = tempAx.ZLim;
         app.UIAxes_2.XColor = [1 1 1];
         app.UIAxes_2.YColor = [1 1 1];
            app.UIAxes_2.ZColor = [1 1 1];
            app.UIAxes_2.Box = 'on';
            close(tempFig);
        end

%----------------------------------------------------------------------------------------------------------------------------------------------------------------
        function plotReprojectionErrors(app, stereoParams)
             tempFig = figure('Visible', 'off');
             showReprojectionErrors(stereoParams);
            tempAx = gca;

            cla(app.UIAxes);
            copyobj(allchild(tempAx), app.UIAxes);
            app.UIAxes.XLim = tempAx.XLim;
            app.UIAxes.YLim = tempAx.YLim;
            app.UIAxes.XColor = [1 1 1];
            app.UIAxes.YColor = [1 1 1];
            app.UIAxes.Box = 'on';
            close(tempFig);
        end

%----------------------------------------------------------------------------------------------------------------------------------------------------------------   
        function [stereoParams, I1, I2, estimationErrors] = calibrateStereoCameras(app)
            % List all .png images in each selected folder
leftFiles = dir(fullfile(app.LeftImageFolderPath, '*.png'));
rightFiles = dir(fullfile(app.RightImageFolderPath, '*.png'));

% Sort by filename to maintain order
leftFiles = sort({leftFiles.name});
rightFiles = sort({rightFiles.name});

% Check for matching file counts
if numel(leftFiles) ~= numel(rightFiles)
    uialert(app.UIFigure, 'Left and Right image folders must have the same number of images.', 'Image Count Mismatch');
    error('Image count mismatch between left and right folders.');
end

% Construct full file paths
imageFileNames1 = fullfile(app.LeftImageFolderPath, leftFiles);
imageFileNames2 = fullfile(app.RightImageFolderPath, rightFiles);


            detector = vision.calibration.stereo.CheckerboardDetector();
             [imagePoints, ~] = detectPatternPoints(detector, imageFileNames1, imageFileNames2);

            squareSize = 900;  % millimeters
            worldPoints = generateWorldPoints(detector, 'SquareSize', squareSize);

             I1 = imread(imageFileNames1{1});
             I2 = imread(imageFileNames2{1});
            [mrows, ncols, ~] = size(I1);

            [stereoParams, ~, estimationErrors] = estimateCameraParameters(imagePoints, worldPoints, ...
                'EstimateSkew', false, ...
                'EstimateTangentialDistortion', false, ...
                'NumRadialDistortionCoefficients', 2, ...
                'WorldUnits', 'millimeters', ...
                'ImageSize', [mrows, ncols]);
        end
%----------------------------------------------------------------------------------------------------------------------------------------------------------------
        function updateCameraTable(app, stereoParams)
    % Camera positions in world coordinates
    center1 = [0, 0, 0];
    R2 = stereoParams.RotationOfCamera2;
    T2 = stereoParams.TranslationOfCamera2';
    center2 = -R2' * T2;

    f1 = mean(stereoParams.CameraParameters1.FocalLength);
    f2 = mean(stereoParams.CameraParameters2.FocalLength);

    app.UITable.Data = {
        'Left',  center1(1), center1(2), center1(3), f1;
        'Right', center2(1), center2(2), center2(3), f2;
    };
end
%----------------------------------------------------------------------------------------------------------------------------------------------------------------

        function plotResults(app)
            TruthData = app.GroundTruthData;
            Measured = app.MeasuredData;
            
            if size(TruthData,1) ~= size(Measured,1)
                uialert(app.UIFigure, 'Datasets must have the same number of points!', 'Size Error');
                return;
            end

            X_gt = TruthData(:,1);
            Y_gt = TruthData(:,2);
            Z_gt = TruthData(:,3);

            X_measured = Measured(:,1);
            Y_measured = Measured(:,2);
            Z_measured = Measured(:,3);
            
            % Plot for X Axis
            plot(app.BallAccuracyX, X_gt, X_measured, 'DisplayName', 'Measured');
            hold(app.BallAccuracyX, 'on');
            plot(app.BallAccuracyX, X_gt, X_gt, 'DisplayName', 'Ideal');
            hold(app.BallAccuracyX, 'off');
            grid(app.BallAccuracyX, 'on');
            xlabel(app.BallAccuracyX, 'Actual X');
            ylabel(app.BallAccuracyX, 'Measured X');
            title(app.BallAccuracyX, 'Horizontal (X-axis)');
            legend(app.BallAccuracyX, 'Location', 'best');

            % Plot for Y Axis
            plot(app.BallAccuracyY, Y_gt, Y_measured, 'DisplayName', 'Measured');
            hold(app.BallAccuracyY, 'on');
            plot(app.BallAccuracyY, Y_gt, Y_gt, 'DisplayName', 'Ideal');
            hold(app.BallAccuracyY, 'off');
            grid(app.BallAccuracyY, 'on');
            xlabel(app.BallAccuracyY, 'Actual Y');
            ylabel(app.BallAccuracyY, 'Measured Y');
            title(app.BallAccuracyY, 'Vertical (Y-axis)');
            legend(app.BallAccuracyY, 'Location', 'best');

            % Plot for Z Axis
            plot(app.BallAccuracyZ, Z_gt, Z_measured, 'DisplayName', 'Measured');
            hold(app.BallAccuracyZ, 'on');
            plot(app.BallAccuracyZ, Z_gt, Z_gt, 'DisplayName', 'Ideal');
            hold(app.BallAccuracyZ, 'off');
            grid(app.BallAccuracyZ, 'on');
            xlabel(app.BallAccuracyZ, 'Actual Z');
            ylabel(app.BallAccuracyZ, 'Measured Z');
            title(app.BallAccuracyZ, 'Depth (Z-axis)');
            legend(app.BallAccuracyZ, 'Location', 'best');

            abs_error_X = abs(X_measured - X_gt);
            abs_error_Y = abs(Y_measured - Y_gt);
            abs_error_Z = abs(Z_measured - Z_gt);

            %max_error_X = max(abs_error_X);
            %max_error_Y = max(abs_error_Y);
            %max_error_Z = max(abs_error_Z);

            accuracy_X = (1 - mean(abs_error_X) / mean(abs(X_gt))) * 100;
            accuracy_Y = (1 - mean(abs_error_Y) / mean(abs(Y_gt))) * 100;
            accuracy_Z = (1 - mean(abs_error_Z) / mean(abs(Z_gt))) * 100;
            % mean value as overall accuracy
            overall_accuracy = mean([accuracy_X, accuracy_Y, accuracy_Z]);


            % === Display on GUI ===
            app.OverallAccuracyLabel.Text = sprintf('System Accuracy: %.2f%%', overall_accuracy);

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            thisFile = mfilename('fullpath');
            appRoot = fileparts(thisFile);
            app.CORFolderPath = fullfile(appRoot, "CoefficientRestitution");
            if ~exist(app.CORFolderPath, "dir")
                uialert(app.UIFigure, "CoefficientRestitution folder not found.", "Path Error");
            else
                addpath(app.CORFolderPath);      % makes baselineCOR / measureCOR visible
            end

            %Static Accuracy Camera Plot
            img = imread("SysAccuracyCamPos/ErrorPlot.png");
            axes(app.CamAccuracy);
            imshow(img, 'Parent', app.CamAccuracy);
            axis(app.CamAccuracy, 'image');
            axis(app.CamAccuracy, 'off');
            max_error_cm = 0.1455;   % Example value (centimeters)
            overall_accuracy = 98.57;  % Example value (percentage)

   
            app.ErrorLabel.Text = sprintf('Max Error:+/- %.4f', max_error_cm);
            app.AccuracyLabel.Text = sprintf('Retained Accuracy:\n %.2f %%', overall_accuracy);


        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            [stereoParams, I1, I2, estimationErrors] = calibrateStereoCameras(app);
            plotReprojectionErrors(app, stereoParams);
            plotExtrinsics(app, stereoParams);
            updateCameraTable(app, stereoParams);


        end

        % Button pushed function: AddImagesButton
        function AddImagesButtonPushed(app, event)
            % BLOCKING: Prompt before selecting left folder
    choice = questdlg('Please select the LEFT camera image folder.', ...
                      'Select Left Folder', ...
                      'Continue', 'Cancel', 'Continue');

    if ~strcmp(choice, 'Continue')
        return;
    end

    leftDir = uigetdir(pwd, 'Select Left Camera Image Folder');
    if leftDir == 0
        return;
    end
    app.LeftImageFolderPath = leftDir;

    % BLOCKING: Prompt before selecting right folder
    choice = questdlg('Now select the RIGHT camera image folder.', ...
                      'Select Right Folder', ...
                      'Continue', 'Cancel', 'Continue');

    if ~strcmp(choice, 'Continue')
        return;
    end

    rightDir = uigetdir(pwd, 'Select Right Camera Image Folder');
    if rightDir == 0
        return;
    end
    app.RightImageFolderPath = rightDir;

    % Final confirmation
    uialert(app.UIFigure, 'Image folders loaded. You may now click Refresh.', 'Success');
        end

        % Button pushed function: BaselineCORButton
        function BaselineCORButtonPushed(app, event)
            runBaselineCOR(app.CORAxes,4,app);
        end

        % Button pushed function: MeasureCORButton
        function MeasureCORButtonPushed(app, event)
            surface = app.SurfaceDropDown.Value;
            try
                e = measureCOR(surface, app);
            catch ME
                uialert(app.UIFigure, ME.message, "COR Test Error");
            end
        end

        % Callback function
        function SelectFileDropDownValueChanged(app, event)
       

        end

        % Button pushed function: ServeVolleyButton
        function ServeVolleyButtonPushed(app, event)
          thisFile = mfilename('fullpath');
            appRoot = fileparts(thisFile);
            dataFolder = fullfile(appRoot,'TennisTracker', 'data');

            if ~exist(dataFolder, 'dir')
                uialert(app.UIFigure, 'Data folder not found.', 'Path Error');
                return;
            end

            [fileName, filePath] = uigetfile('*.dat', 'Select a .dat file', dataFolder);

            if isequal(fileName, 0)
                disp('User cancelled file selection.');
                return;
            end

            fullFile = fullfile(filePath, fileName);

    % Read the selected file
            data = readmatrix(fullFile);

    % Data size
            dataSize = size(data);
            numFrames = dataSize(1);

    % Default timing (adjust depending on your needs)
            frameTimeMs = 50; % ms


    % Connect to Blender if not connected
            if isempty(app.Client)
                server_ip   = '127.0.0.1';
                server_port = 55001;
                app.Client = tcpclient(server_ip,server_port,"Timeout",20);
                disp('Connected to server');
            end

            width = 752;
            height = 480;

     %take pic of the baseline
    x = 100;
    y = 100;
    z = 100;
    x1 =11;
    y1 =-5;
    z1 =8.2;
    
            image = blenderLink(app.Client, width, height, x, y, z, 0, 0, 0, "tennisBall");
                    imagesc(app.ReplayGraph, image);
            image3 = blenderLink(app.Client,width,height,x1,y1,z1,45,0,90,"Left Camera");
                    imagesc(app.ReplayGraph, image3)

            %save%pick%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %    HERE baseline pic
            %
            %
             y1 = 5;
                image3 = blenderLink(app.Client,width,height,x1,y1,z1,45,0,90,"Left Camera");
                imagesc(app.ReplayGraph, image3)

             %save%pick%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %    HERE baseline pic
            %
            %

        rows = numFrames /50;
        
    servefilesleft =  ["m1.fig";"m2.fig";"m3.fig";"m4.fig";"m5.fig";"m6.fig";"m7.fig";"m8.fig";"m9.fig";"m10.fig";"m11.fig";"m12.fig";"m13.fig";"m14.fig";"m15.fig";"m16.fig";"m17.fig";"m18.fig";"m19.fig";"m20.fig";];
    servefilesright = ["m1_.fig";"m2_.fig";"m3_.fig";"m4_.fig";"m5_.fig";"m6_.fig";"m7_.fig";"m8_.fig";"m9_.fig";"m10_.fig";"m11_.fig";"m12_.fig";"m13_.fig";"m14_.fig";"m15_.fig";"m16_.fig";"m17_.fig";"m18_.fig";"m19_.fig";"m20_.fig";];
    
    i = 1;
    ii = 1;

    if numFrames == 1956
        frameTimeMs = 100;
    end
    if numFrames == 1062
        frameTimeMs = 75;
    end
     if numFrames == 3398
        frameTimeMs = 200;
     end
     if numFrames == 477
        frameTimeMs = 50;
     end
     if numFrames == 882
        frameTimeMs = 75;
     end

    for counter = 1:frameTimeMs:numFrames
    x = data(counter,1);
    y = data(counter,3);
    z = data(counter,2);
    x1 =11;
    y1 =-5;
    z1 =8.2;

     
    image = blenderLink(app.Client,width,height,x,y,z,0,0,0,"tennisBall");
    imagesc(app.ReplayGraph, image)
    image3 = blenderLink(app.Client,width,height,x1,y1,z1,45,0,90,"Left Camera");
    imagesc(app.ReplayGraph, image3)
   if i <=rows
        %exportgraphics(app.ReplayGraph,servefilesleft(i,1))
        %save%pick%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %    HERE
            %
            %
        %savefig(servefilesleft(i,1))
        i = i +1;
   end

    y1 = 5;
    image3 = blenderLink(app.Client,width,height,x1,y1,z1,45,0,90,"Left Camera");
    imagesc(app.ReplayGraph, image3)
    if ii <=rows
        %exportgraphics(app.ReplayGraph,servefilesleft(ii,1))
        %save%pick%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %    HERE
            %
            %
        %savefig(servefilesright(ii,1))
        ii = ii +1;
    else
        break;
    end
    %set(gcf, 'Position', get(0, 'Screensize'));
   % axis off
    end


       sampledServeCounter = 1;
        for counter = 1:frameTimeMs:numFrames
            x = data(counter,1);
            y = data(counter,3);
            z = data(counter,2);

            app.sampledServe(1, sampledServeCounter) = x;
            app.sampledServe(2, sampledServeCounter) = y;
            app.sampledServe(3, sampledServeCounter) = z;

            sampledServeCounter = sampledServeCounter + 1;
        end
            scatter3(app.TrajectoryGraph,app.sampledServe(1,:),app.sampledServe(2,:),app.sampledServe(3,:))

        end

        % Button pushed function: ReplayButton
        function ReplayButtonPushed(app, event)
            thisFile = mfilename('fullpath');
            appRoot = fileparts(thisFile);
            dataFolder = fullfile(appRoot,'TennisTracker', 'data');

            if ~exist(dataFolder, 'dir')
                uialert(app.UIFigure, 'Data folder not found.', 'Path Error');
                return;
            end

            [fileName, filePath] = uigetfile('*.dat', 'Select a .dat file', dataFolder);

            if isequal(fileName, 0)
                disp('User cancelled file selection.');
                return;
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%Change code ^^^^^^^^^^^ to take in the dat file instead of making user choice it%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            fullFile = fullfile(filePath, fileName);

            if isempty(app.Client)
                server_ip   = '127.0.0.1';
                server_port = 55001;
                app.Client = tcpclient(server_ip,server_port,"Timeout",20);
                disp('Connected to server');
            end

            width = 752;
            height = 480;


    % Read the selected file
            data = readmatrix(fullFile);

    % Data size
            dataSize = size(data);
            numFrames = dataSize(1);

            replay = 1:numFrames; %1:frameTimeMs:numFrames;
            replayCount = length(replay);

            sampledServeCounter = 1;
        for counter = 1:1:numFrames
            x = data(counter,1);
            y = data(counter,3);
            z = data(counter,2);

            app.sampledServe(1, sampledServeCounter) = x;
            app.sampledServe(2, sampledServeCounter) = y;
            app.sampledServe(3, sampledServeCounter) = z;

            sampledServeCounter = sampledServeCounter + 1;
        end
            scatter3(app.TrajectoryGraph,app.sampledServe(1,:),app.sampledServe(2,:),app.sampledServe(3,:))


             % Define Camera Positions and Rotations
            CameraPositions = [
                7.5484 4.3444 1.5846;
                0.47844 12.38 1.1546;
                -1.1816 11 0.91456
            ];
            CameraRotations = [
                91 0 99;
                81 0 178;
                100 0 231
            ];
                % 3 Replays with different cameras
            for cam = 1:3
                x1 = CameraPositions(cam, 1);
                y1 = CameraPositions(cam, 2);
                z1 = CameraPositions(cam, 3);
                rx = CameraRotations(cam, 1);
                ry = CameraRotations(cam, 2);
                rz = CameraRotations(cam, 3);

                for i = 2:replayCount
                    idx = replay(i);

            % Ball position
                    x = data(idx, 1);
                    y = data(idx, 3);
                    z = data(idx, 2);

            % Send ball to Blender
                    image = blenderLink(app.Client, width, height, x, y, z, 0, 0, 0, "tennisBall");
                    imagesc(app.ReplayGraph, image);

            % Send camera view
                    image3 = blenderLink(app.Client, width, height, x1, y1, z1, rx, ry, rz, "Left Camera");
                    imagesc(app.ReplayGraph, image3);
                
            end  
            end
        end

        % Button pushed function: LoadMeasuredDataButton
        function LoadMeasuredDataButtonPushed(app, event)
            %looks up calculated coordinates and displays them 
            thisFile = mfilename('fullpath');
            appRoot = fileparts(thisFile);
            dataFolder = fullfile(appRoot, 'TennisTracker', 'data');

            if ~exist(dataFolder, 'dir')
                uialert(app.UIFigure, 'Data folder not found.', 'Path Error');
                return;
            end

            [fileName, filePath] = uigetfile('*.dat', 'Select Measured Data File', dataFolder);

            if isequal(fileName, 0)
                disp('User cancelled.');
                return;
            end

            app.MeasuredData = readmatrix(fullfile(filePath, fileName));
            if ~isempty(app.GroundTruthData) && ~isempty(app.MeasuredData)
                plotResults(app);
            end

        end

        % Button pushed function: LoadGroundTruthButton
        function LoadGroundTruthButtonPushed(app, event)
             %looks up expected ground truths and displays them 
            thisFile = mfilename('fullpath');
            appRoot = fileparts(thisFile);
            dataFolder = fullfile(appRoot, 'TennisTracker', 'data');

            if ~exist(dataFolder, 'dir')
                uialert(app.UIFigure, 'Data folder not found.', 'Path Error');
                return;
            end

            [fileName, filePath] = uigetfile('*.dat', 'Select Ground Truth File', dataFolder);

            if isequal(fileName, 0)
                disp('User cancelled.');
                return;
            end

            app.GroundTruthData = readmatrix(fullfile(filePath, fileName));
            if ~isempty(app.GroundTruthData) && ~isempty(app.MeasuredData)
                plotResults(app);
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TennisTrackerTab = uitab(app.TabGroup);
            app.TennisTrackerTab.Title = 'Tennis Tracker';
            app.Image = uiimage(app.TennisTrackerTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'wiisportscourt.jpg');
            app.StereoCalibratorTab = uitab(app.TabGroup);
            app.StereoCalibratorTab.Title = 'Stereo Calibrator';
            app.UIAxes = uiaxes(app.StereoCalibratorTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.StereoCalibratorTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.AddImagesButton = uibutton(app.StereoCalibratorTab, 'push');
            app.AddImagesButton.ButtonPushedFcn = createCallbackFcn(app, @AddImagesButtonPushed, true);
            app.AddImagesButton.Text = 'Add Images';
            app.RefreshButton = uibutton(app.StereoCalibratorTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.BoardPlacementVisualizationLabel = uilabel(app.StereoCalibratorTab);
            app.BoardPlacementVisualizationLabel.Text = 'Board Placement Visualization';
            app.PropogationErrorLabel = uilabel(app.StereoCalibratorTab);
            app.PropogationErrorLabel.Text = 'Propogation Error';
            app.UITable = uitable(app.StereoCalibratorTab);
            app.UITable.ColumnName = {'Camera'; 'X'; 'Y'; 'Z'; 'f'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.GuideTextAreaLabel = uilabel(app.StereoCalibratorTab);
            app.GuideTextAreaLabel.Text = 'Guide';
            app.GuideTextArea = uitextarea(app.StereoCalibratorTab);
            app.GuideTextArea.Value = {'Please add file path for left and right images'};
            app.CORTab = uitab(app.TabGroup);
            app.CORTab.Title = 'COR';
            app.CORAxes = uiaxes(app.CORTab);
            app.BaselineCORButton = uibutton(app.CORTab, 'push');
            app.BaselineCORButton.ButtonPushedFcn = createCallbackFcn(app, @BaselineCORButtonPushed, true);
            app.BaselineCORButton.Text = 'Baseline COR';
            app.MeasureCORButton = uibutton(app.CORTab, 'push');
            app.MeasureCORButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureCORButtonPushed, true);
            app.MeasureCORButton.Text = 'Measure COR';
            app.CORTable = uitable(app.CORTab);
            app.CORTable.ColumnName = {'Surface'; 'Bounce #'; 'Velocity'; 'E'};
            app.CORTable.RowName = {};
            app.SurfaceDropDownLabel = uilabel(app.CORTab);
            app.SurfaceDropDownLabel.Text = 'Surface';
            app.SurfaceDropDown = uidropdown(app.CORTab);
            app.SurfaceDropDown.Items = {'Clay', 'Grass', 'Hard'};
            app.SurfaceDropDown.Value = 'Clay';
            app.AccuracyPlotsTab = uitab(app.TabGroup);
            app.AccuracyPlotsTab.Title = 'Accuracy Plots';
            app.CamAccuracy = uiaxes(app.AccuracyPlotsTab);
            app.CamAccuracy.XTick = [];
            app.CamAccuracy.YTick = [];
            app.BallAccuracyX = uiaxes(app.AccuracyPlotsTab);
            title(app.BallAccuracyX, 'Title')
            xlabel(app.BallAccuracyX, 'X')
            ylabel(app.BallAccuracyX, 'Y')
            zlabel(app.BallAccuracyX, 'Z')
            app.BallAccuracyY = uiaxes(app.AccuracyPlotsTab);
            title(app.BallAccuracyY, 'Title')
            xlabel(app.BallAccuracyY, 'X')
            ylabel(app.BallAccuracyY, 'Y')
            zlabel(app.BallAccuracyY, 'Z')
            app.BallAccuracyZ = uiaxes(app.AccuracyPlotsTab);
            title(app.BallAccuracyZ, 'Title')
            xlabel(app.BallAccuracyZ, 'X')
            ylabel(app.BallAccuracyZ, 'Y')
            zlabel(app.BallAccuracyZ, 'Z')
            app.BasedonBallPositionLabel = uilabel(app.AccuracyPlotsTab);
            app.BasedonBallPositionLabel.Text = 'Based on Ball Position';
            app.BasedonCameraPositionLabel = uilabel(app.AccuracyPlotsTab);
            app.BasedonCameraPositionLabel.Text = 'Based on Camera Position ';
            app.SystemAccuracyLabel = uilabel(app.AccuracyPlotsTab);
            app.SystemAccuracyLabel.Text = 'System Accuracy';
            app.ErrorLabel = uilabel(app.AccuracyPlotsTab);
            app.ErrorLabel.Text = 'Error';
            app.AccuracyLabel = uilabel(app.AccuracyPlotsTab);
            app.AccuracyLabel.Text = 'Accuracy %';
            app.OverallAccuracyLabel = uilabel(app.AccuracyPlotsTab);
            app.OverallAccuracyLabel.Text = 'Overall Accuracy:';
            app.LoadGroundTruthButton = uibutton(app.AccuracyPlotsTab, 'push');
            app.LoadGroundTruthButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGroundTruthButtonPushed, true);
            app.LoadGroundTruthButton.Text = 'Load Ground Truth';
            app.LoadMeasuredDataButton = uibutton(app.AccuracyPlotsTab, 'push');
            app.LoadMeasuredDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMeasuredDataButtonPushed, true);
            app.LoadMeasuredDataButton.Text = 'Load Measured Data';
            app.PlaybackTab = uitab(app.TabGroup);
            app.PlaybackTab.Title = 'Playback';
            app.ReplayGraph = uiaxes(app.PlaybackTab);
            app.ReplayGraph.XTick = [];
            app.ReplayGraph.YTick = [];
            app.TrajectoryGraph = uiaxes(app.PlaybackTab);
            title(app.TrajectoryGraph, 'Title')
            xlabel(app.TrajectoryGraph, 'X')
            ylabel(app.TrajectoryGraph, 'Y')
            zlabel(app.TrajectoryGraph, 'Z')
            app.PlayBackMenuLabel = uilabel(app.PlaybackTab);
            app.PlayBackMenuLabel.Text = 'PlayBack Menu';
            app.TrajectoryLabel = uilabel(app.PlaybackTab);
            app.TrajectoryLabel.Text = 'Trajectory';
            app.ReplayLabel = uilabel(app.PlaybackTab);
            app.ReplayLabel.Text = 'Replay';
            app.ServeVolleyButton = uibutton(app.PlaybackTab, 'push');
            app.ServeVolleyButton.ButtonPushedFcn = createCallbackFcn(app, @ServeVolleyButtonPushed, true);
            app.ServeVolleyButton.Text = 'Serve/Volley';
            app.ReplayButton = uibutton(app.PlaybackTab, 'push');
            app.ReplayButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayButtonPushed, true);
            app.ReplayButton.Text = 'Replay';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TennisProjectGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end